classdef DataCleaner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TurnCleanerUIFigure     matlab.ui.Figure
        filenameButton          matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        Aux_UIAxes              matlab.ui.control.UIAxes
        DeleteTurnButton        matlab.ui.control.Button
        VariableUIAxes          matlab.ui.control.UIAxes
        TurnSpinnerLabel        matlab.ui.control.Label
        TurnSpinner             matlab.ui.control.Spinner
        TablesDropDownLabel     matlab.ui.control.Label
        TablesDropDown          matlab.ui.control.DropDown
        VariablesDropDownLabel  matlab.ui.control.Label
        VariablesDropDown       matlab.ui.control.DropDown
        AuxvarDropDownLabel     matlab.ui.control.Label
        AuxvarDropDown          matlab.ui.control.DropDown
        CheckBox                matlab.ui.control.CheckBox
        ListBox                 matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        data % Description
        pathname= 'C:\Users\longoar001\Desktop\AL_desk\Data test\Wear\Vizzola_Golf';
        %'\\group.pirelli.com\PIRELLI_SHARES\IT_MILAN5_TY5004_CYBERTYRE\TestData\Wear';
        filename
        tables
        vars
    end
    
    methods (Access = private)
        
        function bad_file(app)
            uialert(app.TurnCleanerUIFigure,'Bad file','ACHTUNG')
            app.VariablesEditField.Value='-';
            app.TurnSpinner.Enable='off';
            app.SaveButton.Enable='off';
            app.DeleteTurnButton.Enable='off';
        end
        
        function plots(app,iTurn)
            cla(app.VariableUIAxes,'reset')
            grid(app.VariableUIAxes,'on')
            cla(app.Aux_UIAxes,'reset')
            plot(app.VariableUIAxes,app.data.(app.TablesDropDown.Value).(app.VariablesDropDown.Value))
            title(app.VariableUIAxes,app.VariablesDropDown.Value)
            if not(isempty(iTurn)) 
                hold(app.VariableUIAxes,'on')
                plot(app.VariableUIAxes,iTurn,app.data.(app.TablesDropDown.Value).(app.VariablesDropDown.Value)(iTurn),...
                    'Marker','o','Color','m')
                hold(app.VariableUIAxes,'off')
                
                if app.CheckBox.Value
                     grid(app.VariableUIAxes,'on')
                     
                     if isnumeric(app.data.(app.TablesDropDown.Value).(app.AuxvarDropDown.Value)(app.TurnSpinner.Value))
                         plot(app.Aux_UIAxes,app.data.(app.TablesDropDown.Value).(app.AuxvarDropDown.Value)(app.TurnSpinner.Value),...
                             'Marker','o','Color','b')
                     elseif iscell(app.data.(app.TablesDropDown.Value).(app.AuxvarDropDown.Value)(app.TurnSpinner.Value))
                         plot(app.Aux_UIAxes,app.data.(app.TablesDropDown.Value).(app.AuxvarDropDown.Value){app.TurnSpinner.Value},...
                             'Marker','o','Color','b')
                     else
                         
                         uialert(app.TurnCleanerUIFigure,'Variable not plottable','Achtung')
                     end
                end
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: filenameButton
        function filenameButtonPushed(app, event)
            Pathname =uigetdir([app.pathname]);
            if isempty(Pathname) 
                figure(app.TurnCleanerUIFigure)
                return
            end
            
            app.vars = {};
            app.tables={};
            app.data = [];
       
            app.pathname=Pathname;
            app.filenameButton.Text=app.pathname;
            
            files=dir([Pathname,filesep,'*.mat']);
            
            app.ListBox.Items = {files.name};
            

            figure(app.TurnCleanerUIFigure)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            d = uiprogressdlg(app.TurnCleanerUIFigure,'Title','Saving file',...
                'Indeterminate','on');
            pippo=app.data;
            save([app.pathname,filesep,app.filename],'-struct','pippo')
            close(d)
        end

        % Button pushed function: DeleteTurnButton
        function DeleteTurnButtonPushed(app, event)
            app.data.(app.TablesDropDown.Value)(app.TurnSpinner.Value,:)=[];
            app.TurnSpinner.Limits=[1 size(app.data.(app.TablesDropDown.Value),1)];
            plots(app,app.TurnSpinner.Value)
        end

        % Value changed function: TurnSpinner
        function TurnSpinnerValueChanged(app, event)
            value = app.TurnSpinner.Value;
            
            plots(app,value)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            app.AuxvarDropDown.Enable = value;
            app.AuxvarDropDown.ValueChangedFcn(app,event)
        end

        % Value changed function: AuxvarDropDown
        function AuxvarDropDownValueChanged(app, event)
      
            plots(app,app.TurnSpinner.Value)

        end

        % Value changed function: TablesDropDown
        function TablesDropDownValueChanged(app, event)
            value = app.TablesDropDown.Value;
            if size(app.data.(app.TablesDropDown.Value),1)>1
                app.VariablesDropDown.Items = app.data.(value).Properties.VariableNames;
                app.AuxvarDropDown.Items= app.data.(value).Properties.VariableNames;
                app.TurnSpinner.Limits=[1 size(app.data.(app.TablesDropDown.Value),1)];
                
                
                app.VariablesDropDown.Enable = 'on';
                app.TurnSpinner.Enable = 'on';
                VariablesDropDownValueChanged(app,event)
            else
                uialert(app.TurnCleanerUIFigure,'Selected table has no multiple rows','')
            end
        end

        % Value changed function: VariablesDropDown
        function VariablesDropDownValueChanged(app, event)
            value = app.VariablesDropDown.Value;
            
            plots(app,app.TurnSpinner.Value)
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)


            app.vars = {};
            app.tables={};
            app.data = [];


            app.filename=app.ListBox.Value;
            app.data=load([app.pathname,filesep,app.filename]);

             fields=fieldnames(app.data)';
                
            
            app.TablesDropDown.Items = fields(structfun(@istable,app.data));
            app.TablesDropDown.Enable='on';
            app.TablesDropDown.ValueChangedFcn(app,event)
            
            app.SaveButton.Enable='on';
            app.DeleteTurnButton.Enable='on';
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TurnCleanerUIFigure = uifigure('Visible', 'off');
            app.TurnCleanerUIFigure.Name = 'TurnCleaner';
            app.filenameButton = uibutton(app.TurnCleanerUIFigure, 'push');
            app.filenameButton.ButtonPushedFcn = createCallbackFcn(app, @filenameButtonPushed, true);
            app.filenameButton.Text = '-filename-';
            app.SaveButton = uibutton(app.TurnCleanerUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.Aux_UIAxes = uiaxes(app.TurnCleanerUIFigure);
            title(app.Aux_UIAxes, 'Aux')
            xlabel(app.Aux_UIAxes, 'Samples')
            ylabel(app.Aux_UIAxes, 'Y')
            app.DeleteTurnButton = uibutton(app.TurnCleanerUIFigure, 'push');
            app.DeleteTurnButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTurnButtonPushed, true);
            app.DeleteTurnButton.Enable = 'off';
            app.DeleteTurnButton.Text = 'DeleteTurn';
            app.VariableUIAxes = uiaxes(app.TurnCleanerUIFigure);
            title(app.VariableUIAxes, '')
            xlabel(app.VariableUIAxes, '')
            ylabel(app.VariableUIAxes, 'Y')
            app.TurnSpinnerLabel = uilabel(app.TurnCleanerUIFigure);
            app.TurnSpinnerLabel.Enable = 'off';
            app.TurnSpinnerLabel.Text = 'Turn';
            app.TurnSpinner = uispinner(app.TurnCleanerUIFigure);
            app.TurnSpinner.Limits = [1 2];
            app.TurnSpinner.RoundFractionalValues = 'on';
            app.TurnSpinner.ValueDisplayFormat = '%.0f';
            app.TurnSpinner.ValueChangedFcn = createCallbackFcn(app, @TurnSpinnerValueChanged, true);
            app.TurnSpinner.Enable = 'off';
            app.TurnSpinner.Value = 1;
            app.TablesDropDownLabel = uilabel(app.TurnCleanerUIFigure);
            app.TablesDropDownLabel.Enable = 'off';
            app.TablesDropDownLabel.Text = 'Tables';
            app.TablesDropDown = uidropdown(app.TurnCleanerUIFigure);
            app.TablesDropDown.Items = {};
            app.TablesDropDown.ValueChangedFcn = createCallbackFcn(app, @TablesDropDownValueChanged, true);
            app.TablesDropDown.Enable = 'off';
            app.TablesDropDown.Value = {};
            app.VariablesDropDownLabel = uilabel(app.TurnCleanerUIFigure);
            app.VariablesDropDownLabel.Enable = 'off';
            app.VariablesDropDownLabel.Text = 'Variables';
            app.VariablesDropDown = uidropdown(app.TurnCleanerUIFigure);
            app.VariablesDropDown.Items = {};
            app.VariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDownValueChanged, true);
            app.VariablesDropDown.Enable = 'off';
            app.VariablesDropDown.Value = {};
            app.AuxvarDropDownLabel = uilabel(app.TurnCleanerUIFigure);
            app.AuxvarDropDownLabel.Enable = 'off';
            app.AuxvarDropDownLabel.Text = 'Aux var';
            app.AuxvarDropDown = uidropdown(app.TurnCleanerUIFigure);
            app.AuxvarDropDown.Items = {};
            app.AuxvarDropDown.ValueChangedFcn = createCallbackFcn(app, @AuxvarDropDownValueChanged, true);
            app.AuxvarDropDown.Enable = 'off';
            app.AuxvarDropDown.Value = {};
            app.CheckBox = uicheckbox(app.TurnCleanerUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ListBox = uilistbox(app.TurnCleanerUIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            % Show the figure after all components are created
            app.TurnCleanerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataCleaner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TurnCleanerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TurnCleanerUIFigure)
        end
    end
end